classdef Tire_Scanner_GUI_cont_S2022 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Tyre_Scanner                   matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        FrequencyPointsEditFieldLabel  matlab.ui.control.Label
        FrequencyPointsEditField       matlab.ui.control.NumericEditField
        StartFrequencyGHzEditFieldLabel  matlab.ui.control.Label
        StartFrequencyGHzEditField     matlab.ui.control.NumericEditField
        EndFrequencyGHzEditFieldLabel  matlab.ui.control.Label
        EndFrequencyGHzEditField       matlab.ui.control.NumericEditField
        TireRadiusmmEditFieldLabel     matlab.ui.control.Label
        TireRadiusmmEditField          matlab.ui.control.NumericEditField
        DataFileEditFieldLabel         matlab.ui.control.Label
        DataFileEditField              matlab.ui.control.EditField
        GraphButton                    matlab.ui.control.Button
        CalibrationFileEditFieldLabel  matlab.ui.control.Label
        CalibrationFileEditField       matlab.ui.control.EditField
        NotificationsTextAreaLabel     matlab.ui.control.Label
        NotificationsTextArea          matlab.ui.control.TextArea
        MeasureButton                  matlab.ui.control.Button
        IFBandwidthkHzEditFieldLabel   matlab.ui.control.Label
        IFBandwidthkHzEditField        matlab.ui.control.NumericEditField
        MotorControlPanel              matlab.ui.container.Panel
        ConnecttoMotorButton           matlab.ui.control.Button
        JogMotorForwardButton          matlab.ui.control.Button
        ScanrangedegEditFieldLabel     matlab.ui.control.Label
        ScanrangedegEditField          matlab.ui.control.NumericEditField
        NumberofsamplesEditFieldLabel  matlab.ui.control.Label
        NumberofsamplesEditField       matlab.ui.control.NumericEditField
        HomeMotorButton                matlab.ui.control.Button
        MotorspeeddegsecLabel          matlab.ui.control.Label
        MotorspeeddegsecEditField      matlab.ui.control.NumericEditField
        IPAddressEditFieldLabel        matlab.ui.control.Label
        IPAddressEditField             matlab.ui.control.EditField
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    end

    methods (Access = private)
    
        %function create_components(app)
           % app.SamplingPositionsSweepsEditField.Value = 130;
           % app.FrequencyPointsEditField.Value = 1001;
           % app.StartFrequencyGHzEditField.Value = 220;
           % app.EndFrequencyGHzEditField.Value = 320;
           % app.FrequencyDeltaEditField.Value = 0.1;
           % app.StepEditField.Value = 0.5;
           % app.CrossExEditField.Value = 0;
           % app.TireRadiusmmEditField.Value = 1;
           % app.DataFileEditField.Value = 'data.mat';
           
        %end
        
    end
    
    methods (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SignalProcessingButtonPushed(app, event)
           %This function calls the vna_data_combiner and image_processing
           %code
           %Note that the notifications is done with arrays and is very
           %difficult. May need to find another way to do this.
           
           global z;                    %These need to be global because they are variables created outside of the GUI and so the GUI can't see them unless you make them global variables. 
           global x;                    %The variables made in the GUI do not need ot be global.
           global map_dB;
           %global tempNotificationArray;
           %global NotificationArray1;  %
           
           
            vna_data_combiner(app.SamplingPositionsSweepsEditField.Value,app.FrequencyPointsEditField.Value); %Gives vna_data_combiner the values put into sampling positions and frequency points and calls the function
            NotificationArray{1} = sprintf('%s\n%s','[!] VNA data combiner completed.'); %Create an array to display messages
            app.NotificationsTextArea.Value = NotificationArray; %Display Message
            pause(1); %Pause  for 1 second so that things don't all at once
            tempNotificationArray = NotificationArray; %Temporary variable to store array which may be uncessary, keeps updating size and length of array when used
            NotificationArray1{1} = NotificationArray{1}; %Input old message so it is on the first line
            NotificationArray1{2} = sprintf('%s\n%s','[!] Image processing completed.'); %Add additional message on the second line of array
            pause(1);
            
          
            [z,x,map_dB] = image_processing(app.StartFrequencyGHzEditField.Value, app.EndFrequencyGHzEditField.Value, app.FrequencyDeltaEditField.Value, app.StepEditField.Value, app.CrossExEditField.Value, app.TireRadiusmmEditField.Value, app.DataFileEditField.Value);
            %For whatever reason the above line all has to be on one line!
            %Sorry about that. Matlab...
            app.NotificationsTextArea.Value = NotificationArray1; %Display image processing message, including the vna data message
            pause(1);
            %tempNotificationArray = NotificationArray1; Not used and not
            %sure how functional it is 
          
        end

        % Button down function: Tyre_Scanner
        function Tyre_ScannerButtonDown(app, event)

            
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            
            global z;
            global x;
            global map_dB;
            %global Zc;         These wil need to be global if you attempt to make the image       
            %global Xc;         with all of the settings in the app
            %global range m1 dx; The problem is that it appears some of
            %the commands do not work in the app designer
            %global tempNotificationArray; %Appears this array passing variable is not needed but may be of use at some point
            %global NotificationArray1;
            
            %Start of trying to put final_image code in here, not super straight forward. For time being image just gets pasted in and is mostly okay. 
            
            %[Zc,Xc] = meshgrid(100*z,100*x);
            
            % app.UIAxes.YAxis = Zc;
            % app.UIAxes.XAxis = Xc;
            
            %tempNotificationArray1 = tempNotificationArray;
            %surf(app.UIAxes.YAxis,app.UIAxes.XAxis,map_dB);
            % set(gcf,'color','w');
            % set(gca,'fontsize', 18, 'FontName', 'Times New Roman');
            % shading interp;
            %axis tight;
            % axis equal;
            % view([0 90]);
            % caxis([-40 10]);
            
            %final_image(z, x, map_dB); %Call the final  image function
            
            %The next notifications will erase the old notifications so if you
            %want them then you need to copy and paste them in and then
            %give them 1 and 2 and then increase the other array positions
            %by 2
            
            NotificationArray1{1} = sprintf('%s\n%s','[!] Graph Created'); %Using NotificationArray1 from Run Button Functions 
            %tempNotificationArray1 = NotificationArray1;
            app.NotificationsTextArea.Value= NotificationArray1; %Display array with 1st message added
            %pause(1); 
             
            NotificationArray1{2} = sprintf('%s\n%s','[!] Graph Saved'); %Display array with additonal message added
            app.NotificationsTextArea.Value = NotificationArray1;
            %pause(1);
            %tempNotificationArray1 = NotificationArray1;
            
            %im = imread('Final_Image.fig'); %Read the image created by final_image function
            imshow('Final_Image.png','Parent',app.UIAxes2); % Display image in GUI
            
            NotificationArray1{3} = sprintf('%s\n%s','[!] Final Image Completed');
            app.NotificationsTextArea.Value = NotificationArray1; %Display array with additional message added
            %pause(1);
            %tempNotificationArray1 = NotificationArray1;
            
            %filearg = (app.CalibrationFileEditField.Value); %Get Filename From User - You need to put the file name in before you push the Graph button
%             filename1 = ['Final_Image.png']; %Input Filename from User and add .png argument to end
%             
%             imwrite(im,filename1); %Write new specified png file from user
            
            %NotificationArray1 = tempNotificationArray1; 
            NotificationArray1{4} = sprintf('%s\n%s','[!] Image Saved to Folder'); 
            app.NotificationsTextArea.Value = NotificationArray1; %Display array with addiional message added
            %pause(1);
            tempNotificationArray1 = NotificationArray1;

            
        end

        % Callback function
        function SaveFileButtonPushed(app, event)

            global final_image;
     
            %filearg = (app.CalibrationFileEditField.Value);
            %filename = [filearg,'.png'];
            %saveas('final_image.png',filename);
            
            %The  lines below this were not created by Kevin and Brandon
            %It is made automatically by MATLAB when you add somthing like
            %a text box or push button
        end

        % Button pushed function: ConnecttoMotorButton
        function ConnecttoMotorButtonPushed(app, event)
            global h;   % make h a global variable so it can be used outside the main
            setupmotorcontrollermanual
            app.NotificationsTextArea.Value = sprintf('Connection To Motor Controller Complete');

        end

        % Button pushed function: HomeMotorButton
        function HomeMotorButtonPushed(app, event)
            global h;
            h.MoveHome(0,0); %Call this function to home the motor. Can be called as many times as you would like.
            app.NotificationsTextArea.Value = sprintf('Home Motor Complete');

        end

        % Button pushed function: JogMotorForwardButton
        function JogMotorForwardButtonPushed(app, event)
            global h;
%           h.SetJogStepSize(0,app.ScanrangedegEditField.Value);
%           h.MoveJog(0,1);
%             
            h.SetRelMoveDist(0,app.ScanrangedegEditField.Value);
            h.MoveRelative(0,1);
            disp('Jog Completed');
            app.NotificationsTextArea.Value = sprintf('Jog Complete');
            %h.Jog_Forward(); %Call this function to Jog the Motor in 0.5 degree steps. Can be called as many times as you would like.
        end

        % Callback function
        function JogMotorBackwardButtonPushed(app, event)
            %global h;
%TBD will come back too  
           % h.Jog_Reverse();
            %reverse, only does forwards, doing -1 does not work.
        end

        % Callback function
        function MoveDistanceStepsButtonPushed(app, event)
            global h;
            h.Move_Step(); %This could help to move the motor in steps but dont know this works with
            %MATLAB, think it only works in c++
        end

        % Callback function
        function StopMotorButtonPushed(app, event)
            %global h;
            %Stop_Motor(h); This works, but wont stop the motor. Not really
            %sure what it is documenting
        end

        % Callback function
        function MoveDistanceButton_2Pushed(app, event)
            global h;
            Move_Distance(h,app.ScanrangedegEditField.Value,app.NumberofsamplesEditField.Value); %Move the motor an x degree distance, which is set in the function file and can be changed.
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            global h;
            app.NotificationsTextArea.Value = sprintf('Beginning scan');
            Run_Motor_cont(h,app.ScanrangedegEditField,app.TireRadiusmmEditField,app.MotorspeeddegsecEditField,app.NumberofsamplesEditField,app.StartFrequencyGHzEditField,app.EndFrequencyGHzEditField,app.DataFileEditField,app.FrequencyPointsEditField,app.IFBandwidthkHzEditField,app.IPAddressEditField,app.CalibrationFileEditField)
            app.NotificationsTextArea.Value = sprintf('Scan Complete, Uploading Image');
            imshow('Final_Image.png','Parent',app.UIAxes2);
            app.NotificationsTextArea.Value = sprintf('Graph Complete');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Tyre_Scanner = uifigure('Visible', 'off');
            app.Tyre_Scanner.Name = 'UI Figure';
            app.Tyre_Scanner.ButtonDownFcn = createCallbackFcn(app, @Tyre_ScannerButtonDown, true);
            app.Menu = uimenu(app.Tyre_Scanner);
            app.Menu.Text = 'Menu';
            app.FrequencyPointsEditFieldLabel = uilabel(app.Tyre_Scanner);
            app.FrequencyPointsEditFieldLabel.Text = 'Frequency Points';
            app.FrequencyPointsEditField = uieditfield(app.Tyre_Scanner, 'numeric');
            app.FrequencyPointsEditField.Value = 1001;
            app.StartFrequencyGHzEditFieldLabel = uilabel(app.Tyre_Scanner);
            app.StartFrequencyGHzEditFieldLabel.Text = 'Start Frequency (GHz)';
            app.StartFrequencyGHzEditField = uieditfield(app.Tyre_Scanner, 'numeric');
            app.StartFrequencyGHzEditField.Value = 230;
            app.EndFrequencyGHzEditFieldLabel = uilabel(app.Tyre_Scanner);
            app.EndFrequencyGHzEditFieldLabel.Text = 'End Frequency (GHz)';
            app.EndFrequencyGHzEditField = uieditfield(app.Tyre_Scanner, 'numeric');
            app.EndFrequencyGHzEditField.Value = 330;
            app.TireRadiusmmEditFieldLabel = uilabel(app.Tyre_Scanner);
            app.TireRadiusmmEditFieldLabel.Text = 'Tire Radius (mm)';
            app.TireRadiusmmEditField = uieditfield(app.Tyre_Scanner, 'numeric');
            app.TireRadiusmmEditField.Value = 228.6;
            app.DataFileEditFieldLabel = uilabel(app.Tyre_Scanner);
            app.DataFileEditFieldLabel.Text = 'Data File';
            app.DataFileEditField = uieditfield(app.Tyre_Scanner, 'text');
            app.DataFileEditField.Value = 'data';
            app.GraphButton = uibutton(app.Tyre_Scanner, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.CalibrationFileEditFieldLabel = uilabel(app.Tyre_Scanner);
            app.CalibrationFileEditFieldLabel.Text = 'Calibration File';
            app.CalibrationFileEditField = uieditfield(app.Tyre_Scanner, 'text');
            app.CalibrationFileEditField.Value = 'WR3.4_S11_220330GHz_5MHz_step.cal';
            app.NotificationsTextAreaLabel = uilabel(app.Tyre_Scanner);
            app.NotificationsTextAreaLabel.Text = 'Notifications';
            app.NotificationsTextArea = uitextarea(app.Tyre_Scanner);
            app.MeasureButton = uibutton(app.Tyre_Scanner, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.IFBandwidthkHzEditFieldLabel = uilabel(app.Tyre_Scanner);
            app.IFBandwidthkHzEditFieldLabel.Text = 'IF Bandwidth (kHz)';
            app.IFBandwidthkHzEditField = uieditfield(app.Tyre_Scanner, 'numeric');
            app.IFBandwidthkHzEditField.Value = 10;
            app.MotorControlPanel = uipanel(app.Tyre_Scanner);
            app.MotorControlPanel.Title = '                             Motor Control';
            app.ConnecttoMotorButton = uibutton(app.MotorControlPanel, 'push');
            app.ConnecttoMotorButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoMotorButtonPushed, true);
            app.ConnecttoMotorButton.Text = 'Connect to Motor';
            app.JogMotorForwardButton = uibutton(app.MotorControlPanel, 'push');
            app.JogMotorForwardButton.ButtonPushedFcn = createCallbackFcn(app, @JogMotorForwardButtonPushed, true);
            app.JogMotorForwardButton.Text = 'Jog Motor Forward';
            app.ScanrangedegEditFieldLabel = uilabel(app.MotorControlPanel);
            app.ScanrangedegEditFieldLabel.Text = 'Scan range (deg)';
            app.ScanrangedegEditField = uieditfield(app.MotorControlPanel, 'numeric');
            app.ScanrangedegEditField.Value = 0.12;
            app.NumberofsamplesEditFieldLabel = uilabel(app.MotorControlPanel);
            app.NumberofsamplesEditFieldLabel.Text = 'Number of samples';
            app.NumberofsamplesEditField = uieditfield(app.MotorControlPanel, 'numeric');
            app.NumberofsamplesEditField.Value = 251;
            app.HomeMotorButton = uibutton(app.MotorControlPanel, 'push');
            app.HomeMotorButton.ButtonPushedFcn = createCallbackFcn(app, @HomeMotorButtonPushed, true);
            app.HomeMotorButton.Text = 'Home Motor';
            app.MotorspeeddegsecLabel = uilabel(app.MotorControlPanel);
            app.MotorspeeddegsecLabel.Text = 'Motor speed (deg/sec)';
            app.MotorspeeddegsecEditField = uieditfield(app.MotorControlPanel, 'numeric');
            app.IPAddressEditFieldLabel = uilabel(app.Tyre_Scanner);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.Tyre_Scanner, 'text');
            app.IPAddressEditField.Value = '10.206.161.196';
            app.UIAxes2 = uiaxes(app.Tyre_Scanner);
            title(app.UIAxes2, 'Tire Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.Tyre_Scanner.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tire_Scanner_GUI_cont_S2022

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Tyre_Scanner)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Tyre_Scanner)
        end
    end
end